classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        CheckBox           matlab.ui.control.CheckBox
        CheckBox_2         matlab.ui.control.CheckBox
        Button             matlab.ui.control.Button
        FxxLabel           matlab.ui.control.Label
        FxLabel_2          matlab.ui.control.Label
        FxEditField        matlab.ui.control.EditField
        x1EditFieldLabel   matlab.ui.control.Label
        x1EditField        matlab.ui.control.EditField
        x2EditFieldLabel   matlab.ui.control.Label
        x2EditField        matlab.ui.control.EditField
        Button_2           matlab.ui.control.Button
        RightPanel         matlab.ui.container.Panel
        UIAxes             matlab.ui.control.UIAxes
        fEditFieldLabel    matlab.ui.control.Label
        fEditField         matlab.ui.control.EditField
        xEditFieldLabel    matlab.ui.control.Label
        xEditField         matlab.ui.control.EditField
        fEditField_2Label  matlab.ui.control.Label
        fEditField_2       matlab.ui.control.EditField
        xEditField_2Label  matlab.ui.control.Label
        xEditField_2       matlab.ui.control.EditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
       i;
       t;
       count=0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
   if app.count==1
                grid(app.UIAxes);
                hold(app.UIAxes);
   end
try
            app.fEditField.Visible=false;
            app.xEditField.Visible=false;
            app.fEditField_2.Visible=false;
            app.xEditField_2.Visible=false;
            app.i=1;
            app.t=[0 0; 0 0];
                s=app.FxEditField.Value;
                F=@(x) eval(s);
                x=linspace(str2double(app.x1EditField.Value),str2double(app.x2EditField.Value),300);
                y=F(x);
                plot(app.UIAxes,x,y,'b');
                grid(app.UIAxes);
                hold(app.UIAxes);
                app.count=1;
                if app.CheckBox.Value==true || app.CheckBox_2.Value==true
                   app.Button.Enable=false;
                   app.CheckBox.Enable=false;
                   app.CheckBox_2.Enable=false;
                   app.FxEditField.Enable=false;
                   app.x1EditField.Enable=false;
                   app.x2EditField.Enable=false;
                   app.Button_2.Visible=true;
                   app.Button_2.Enable=true;
                   helpdlg('Выберите две точки на графике','Указание');
                end
catch
      errordlg('Неверно введены данные','Ошибка!')
end


        end

        % Window button down function: UIFigure
        function UIFigureButtonDown(app, event)
           temp = app.UIAxes.CurrentPoint;
        app.t(app.i,1)=temp(1,1);
        app.t(app.i,2)=temp(1,2);
        app.i=app.i+1;

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
try
               s=app.FxEditField.Value;
                xr=app.t;
                first=1;
                second = 2;
                if xr(1,1)>xr(2,1)
                    first=2;
                    second=1;
                end
                    if app.CheckBox.Value == true
                        F=@(x) -1*eval(s);
                        [x_m,y_m]=fminbnd(F,xr(first,1),xr(second,1));
                        y_m=-1.*y_m;
                        plot(app.UIAxes,x_m,y_m,'r*',xr(1,1),xr(1,2),'g*',xr(2,1),xr(2,2),'g*');
                        app.fEditField.Value=num2str(y_m);
                        app.xEditField.Value=num2str(x_m);
                        app.fEditField.Visible=true;
                        app.xEditField.Visible=true;
                    end
                    if app.CheckBox_2.Value == true
                        F=@(x) eval(s);
                        [x_m,y_m]=fminbnd(F,xr(1,1),xr(2,1));
                        plot(app.UIAxes,x_m,y_m,'r*',xr(1,1),xr(1,2),'g*',xr(2,1),xr(2,2),'g*');
                        app.fEditField_2.Value=num2str(y_m);
                        app.xEditField_2.Value=num2str(x_m);
                        app.fEditField_2.Visible=true;
                        app.xEditField_2.Visible=true;
                    end
                   app.Button.Enable=true;
                   app.CheckBox.Enable=true;
                   app.CheckBox_2.Enable=true;
                   app.FxEditField.Enable=true;
                   app.x1EditField.Enable=true;
                   app.x2EditField.Enable=true;
                   app.Button_2.Visible=false;
                   app.Button_2.Enable=false;
catch
    errordlg('Неправильно выбраны точки!','Ошибка!');
end
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CheckBox = uicheckbox(app.LeftPanel);
            app.CheckBox.Text = 'найти максимум';
            app.CheckBox_2 = uicheckbox(app.LeftPanel);
            app.CheckBox_2.Text = 'найти минимум';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = {'построить'; ''};
            app.FxxLabel = uilabel(app.LeftPanel);
            app.FxxLabel.Text = {'Введите функцию F(x):'; '(Перед операциями +, -, * , /, '; '^ с x ставьте точку)'; ''};
            app.FxLabel_2 = uilabel(app.LeftPanel);
            app.FxLabel_2.Text = 'F(x)';
            app.FxEditField = uieditfield(app.LeftPanel, 'text');
            app.x1EditFieldLabel = uilabel(app.LeftPanel);
            app.x1EditFieldLabel.Text = 'x 1';
            app.x1EditField = uieditfield(app.LeftPanel, 'text');
            app.x2EditFieldLabel = uilabel(app.LeftPanel);
            app.x2EditFieldLabel.Text = 'x 2';
            app.x2EditField = uieditfield(app.LeftPanel, 'text');
            app.Button_2 = uibutton(app.LeftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Enable = 'off';
            app.Button_2.Visible = 'off';
            app.Button_2.Text = 'Найти экстремум';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, {'график f(x)'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.fEditFieldLabel = uilabel(app.RightPanel);
            app.fEditFieldLabel.Text = 'Максимум f';
            app.fEditField = uieditfield(app.RightPanel, 'text');
            app.fEditField.Editable = 'off';
            app.fEditField.Visible = 'off';
            app.xEditFieldLabel = uilabel(app.RightPanel);
            app.xEditFieldLabel.Text = 'значение x ';
            app.xEditField = uieditfield(app.RightPanel, 'text');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField.Editable = 'off';
            app.xEditField.Visible = 'off';
            app.fEditField_2Label = uilabel(app.RightPanel);
            app.fEditField_2Label.Text = 'минимум f';
            app.fEditField_2 = uieditfield(app.RightPanel, 'text');
            app.fEditField_2.Editable = 'off';
            app.fEditField_2.Visible = 'off';
            app.xEditField_2Label = uilabel(app.RightPanel);
            app.xEditField_2Label.Text = 'значение x';
            app.xEditField_2 = uieditfield(app.RightPanel, 'text');
            app.xEditField_2.Editable = 'off';
            app.xEditField_2.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end